classdef Segment_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LightningfootagesegmentationUIFigure  matlab.ui.Figure
        SegmentationPanel            matlab.ui.container.Panel
        TextArea3                    matlab.ui.control.TextArea
        TextArea2                    matlab.ui.control.TextArea
        Output_path_textTextArea     matlab.ui.control.TextArea
        EditField_2                  matlab.ui.control.EditField
        EditField_3                  matlab.ui.control.EditField
        EditField                    matlab.ui.control.EditField
        Button                       matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        ComparewithexpectedCheckBox  matlab.ui.control.CheckBox
        Pixel_label_textTextArea     matlab.ui.control.TextArea
        EditField_4                  matlab.ui.control.EditField
        Button_4                     matlab.ui.control.Button
        MakeoverlayCheckBox          matlab.ui.control.CheckBox
        ExcecuteButton               matlab.ui.control.Button
    end

properties (Access = public)
    file;
    path1;
end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ComparewithexpectedCheckBox
        function makeVisiable(app, event)
            value = app.ComparewithexpectedCheckBox.Value;
            if value
                app.Pixel_label_textTextArea.Visible = 'on';
                app.Button_4.Visible = 'on';
                app.EditField_4.Visible = 'on';
            else
                app.Pixel_label_textTextArea.Visible = 'off';
                app.Button_4.Visible = 'off';
                app.EditField_4.Visible = 'off';
            end
            
        end

        % Button pushed function: Button
        function Browse_input(app, event)
            sequenceDir = uigetdir(fullfile('/home/jason/Desktop/reduced/tif/'),'Select the sequence to load');
            if ~sequenceDir
                disp('No directory has been defined')
            else
                app.EditField.Value = sequenceDir;                
            end
            
        end

        % Button pushed function: Button_2
        function Browse_network(app, event)
            [app.file,app.path1] = uigetfile(fullfile('home','jason','Desktop','reduced','alexnet_2019-07-30_'),'*.mat',...
                'Select the Network File to load');
            if ~app.file
                disp('Network file has not been specified')
            else
                app.EditField_2.Value = [app.path1 app.file];
            end           
        end

        % Button pushed function: Button_3
        function Browse_output(app, event)
            outputDir = uigetdir(tempdir,'Select the output diretory');
            if ~outputDir
                disp('No directory has been defined, using standard tmp')
            else
                app.EditField_3.Value = outputDir;
            end
        end

        % Button pushed function: Button_4
        function Browse_labels(app, event)
            labelDir = uigetdir(fullfile('/home/'),'Select the masks to load');
            if ~labelDir
                disp('No directory has been defined')
            else
                app.EditField_4.Value = labelDir;
            end
            
        end

        % Button pushed function: ExcecuteButton
        function runSegmentation(app, event)
            %% Export all the required variable
            getLabelIDs;
            segmentResults;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LightningfootagesegmentationUIFigure = uifigure('Visible', 'off');
            app.LightningfootagesegmentationUIFigure.Name = 'Lightning footage segmentation';
            app.SegmentationPanel = uipanel(app.LightningfootagesegmentationUIFigure);
            app.SegmentationPanel.Title = 'Segmentation';
            app.TextArea3 = uitextarea(app.SegmentationPanel);
            app.TextArea3.Editable = 'off';
            app.TextArea3.Value = {'Network:'};
            app.TextArea2 = uitextarea(app.SegmentationPanel);
            app.TextArea2.Editable = 'off';
            app.TextArea2.Value = {'Input:'};
            app.Output_path_textTextArea = uitextarea(app.SegmentationPanel);
            app.Output_path_textTextArea.Editable = 'off';
            app.Output_path_textTextArea.Value = {'Output path:'};
            app.EditField_2 = uieditfield(app.SegmentationPanel, 'text');
            app.EditField_2.Value = '/home/jason/Desktop/reduced/alexnet_2019-07-30_121421/alexnet_2019-07-30_121421.mat';
            app.EditField_3 = uieditfield(app.SegmentationPanel, 'text');
            app.EditField_3.Value = '/home/jason/Desktop/Testout';
            app.EditField = uieditfield(app.SegmentationPanel, 'text');
            app.EditField.Value = '/home/jason/Desktop/reduced/tif';
            app.Button = uibutton(app.SegmentationPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Browse_input, true);
            app.Button.Icon = 'folder.png';
            app.Button.Tooltip = {'Browse'};
            app.Button.Text = '';
            app.Button_2 = uibutton(app.SegmentationPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Browse_network, true);
            app.Button_2.Icon = 'folder.png';
            app.Button_2.Tooltip = {'Browse'};
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.SegmentationPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Browse_output, true);
            app.Button_3.Icon = 'folder.png';
            app.Button_3.Tooltip = {'Browse'};
            app.Button_3.Text = '';
            app.ComparewithexpectedCheckBox = uicheckbox(app.SegmentationPanel);
            app.ComparewithexpectedCheckBox.ValueChangedFcn = createCallbackFcn(app, @makeVisiable, true);
            app.ComparewithexpectedCheckBox.Tooltip = {'Enable the overlay of the input image and pixel segmentation'};
            app.ComparewithexpectedCheckBox.Text = ' Compare with expected';
            app.Pixel_label_textTextArea = uitextarea(app.SegmentationPanel);
            app.Pixel_label_textTextArea.Editable = 'off';
            app.Pixel_label_textTextArea.Visible = 'off';
            app.Pixel_label_textTextArea.Value = {'Pixel labels:'};
            app.EditField_4 = uieditfield(app.SegmentationPanel, 'text');
            app.EditField_4.Visible = 'off';
            app.EditField_4.Value = '/home/jason/Desktop/reduced/mask';
            app.Button_4 = uibutton(app.SegmentationPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Browse_labels, true);
            app.Button_4.Icon = 'folder.png';
            app.Button_4.Visible = 'off';
            app.Button_4.Tooltip = {'Browse'};
            app.Button_4.Text = '';
            app.MakeoverlayCheckBox = uicheckbox(app.SegmentationPanel);
            app.MakeoverlayCheckBox.Text = ' Make overlay';
            app.ExcecuteButton = uibutton(app.SegmentationPanel, 'push');
            app.ExcecuteButton.ButtonPushedFcn = createCallbackFcn(app, @runSegmentation, true);
            app.ExcecuteButton.Text = 'Excecute';
            % Show the figure after all components are created
            app.LightningfootagesegmentationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Segment_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LightningfootagesegmentationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LightningfootagesegmentationUIFigure)
        end
    end
end