classdef labelLightning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LightningfootagesegmentationUIFigure  matlab.ui.Figure
        SegmentationPanel             matlab.ui.container.Panel
        Networkfile_Edit              matlab.ui.control.EditField
        Outputpath_Edit               matlab.ui.control.EditField
        Inputfolder_Edit              matlab.ui.control.EditField
        Inputfolder_Button            matlab.ui.control.Button
        Networkfile_Button            matlab.ui.control.Button
        Outputpath_Button             matlab.ui.control.Button
        Comparewithexpected_CheckBox  matlab.ui.control.CheckBox
        Pixellabels_Edit              matlab.ui.control.EditField
        Pixellabels_Button            matlab.ui.control.Button
        Makeoverlay_CheckBox          matlab.ui.control.CheckBox
        Excecute_Button               matlab.ui.control.Button
        Inputfolder_Label             matlab.ui.control.Label
        Networkfile_Label             matlab.ui.control.Label
        Outputpath_Label              matlab.ui.control.Label
        Pixellabels_Label             matlab.ui.control.Label
        PreprocessCheckBox            matlab.ui.control.CheckBox
        TextArea                      matlab.ui.control.TextArea
    end

properties (Access = public)
    %export the OutputDs to the workspace
    %assignin('base', 'eventCellArray', eventsCellArray);
    file;
    path1;
    AppPos;
    progressApp;
    output;
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        if exist('appPreferences.mat', 'file')
            load('appPreferences')
            app.Networkfile_Edit.Value = labelLightning_networkFileName;
            app.Inputfolder_Edit.Value =  labelLightning_inputFolderName;
            app.Outputpath_Edit.Value = labelLightning_outputPathName;
        else
            app.Networkfile_Edit.Value = '/home/jason/Desktop/Project Raiden- Working/deeplabv3_256x256_2019-08-18_121943/network.mat';
            app.Outputpath_Edit.Value = '/home/jason/Desktop/temp/';
            app.Inputfolder_Edit.Value = '/home/jason/Desktop/small/tif';
        end
        end

        % Value changed function: Comparewithexpected_CheckBox
        function makeVisiable(app, event)
            value = app.Comparewithexpected_CheckBox.Value;
            if value
                sequenceDir= app.Inputfolder_Edit.Value;
                basePath = fileparts(sequenceDir);
                app.Pixellabels_Edit.Value = strcat(basePath,'/mask');
                app.Pixellabels_Label.Visible = 'on';
                app.Pixellabels_Button.Visible = 'on';
                app.Pixellabels_Edit.Visible = 'on';
            else
                app.Pixellabels_Label.Visible = 'off';
                app.Pixellabels_Button.Visible = 'off';
                app.Pixellabels_Edit.Visible = 'off';
            end
        end
        function enableEdits(app)
            app.PreprocessCheckBox.Enable = 'on';
            app.Networkfile_Button.Enable = 'on';
            app.Networkfile_Edit.Editable = 'on';
            app.Outputpath_Button.Enable = 'on';
            app.Outputpath_Edit.Editable = 'on';
            app.Inputfolder_Button.Enable = 'on';
            app.Inputfolder_Edit.Editable = 'on';
            app.Makeoverlay_CheckBox.Enable = 'on';
            app.Comparewithexpected_CheckBox.Enable = 'on';
            app.Pixellabels_Button.Enable = 'on';
            app.Pixellabels_Edit.Editable = 'on';
            
            app.Excecute_Button.Text = 'Excecute';
            app.Excecute_Button.Enable = 'on';
        end
   
        function disableEdits(app)
            app.Excecute_Button.Text = 'Working...';
            app.Excecute_Button.Enable = 'off';
            
            app.Networkfile_Button.Enable = 'off';
            app.Networkfile_Edit.Editable = 'off';
            app.Outputpath_Button.Enable = 'off';
            app.Outputpath_Edit.Editable = 'off';
            app.Inputfolder_Button.Enable = 'off';
            app.Inputfolder_Edit.Editable = 'off';
            app.Makeoverlay_CheckBox.Enable = 'off';
            app.Comparewithexpected_CheckBox.Enable = 'off';
            app.Pixellabels_Button.Enable = 'off';
            app.Pixellabels_Edit.Editable = 'off';
            app.PreprocessCheckBox.Enable = 'off';
            
        end

        % Button pushed function: Inputfolder_Button
        function Browse_input(app, event)
            sequenceDir = uigetdir(app.Inputfolder_Edit.Value,'Select the sequence to load');
            if ~sequenceDir
                disp('No directory has been defined')
            else
                app.Inputfolder_Edit.Value = sequenceDir;
                basePath = fileparts(sequenceDir);
                app.Pixellabels_Edit.Value = strcat(basePath,'/mask');
            end
            
        end

        % Button pushed function: Networkfile_Button
        function Browse_network(app, event)
                pathToNetworkFile = fileparts(app.Networkfile_Edit.Value);
                [app.file,app.path1] = uigetfile('*.mat',...
                    'Select the Network File to load',pathToNetworkFile);
                if ~app.file
                    disp('Network file has not been specified')
                else
                    app.Networkfile_Edit.Value = [app.path1 app.file];
                end
        end

        % Button pushed function: Outputpath_Button
        function Browse_output(app, event)
            outputDir = uigetdir(app.Outputpath_Edit.Value,'Select the output diretory');
            if ~outputDir
                disp('No directory has been defined, using standard tmp')
            else
                app.Outputpath_Edit.Value = outputDir;
            end
        end

        % Button pushed function: Pixellabels_Button
        function Browse_labels(app, event)
            labelDir = uigetdir(app.Pixellabels_Edit.Value,'Select the masks to load');
            if ~labelDir
                disp('No directory has been defined')
            else
                app.Pixellabels_Edit.Value = labelDir;
                basePath = fileparts(labelDir);
                app.Inputfolder_Edit.Value = strcat(basePath,'/tif');
            end
            
        end

        % Button pushed function: Excecute_Button
        function runSegmentation(app, event)
            %% Export all the required variable
            app.TextArea.Visible = 'off';
            loadLabels;
            disableEdits(app);
            %d = uiprogressdlg(app.LightningfootagesegmentationUIFigure,'Title','Computing SVD',...
        %'Indeterminate','on');
            %app.AppPos = app.LightningfootagesegmentationUIFigure.Position
            %app.LightningfootagesegmentationUIFigure.Visible = 'off';
            
%             segmentResults(app.Networkfile_Edit.Value, app.Inputfolder_Edit.Value, ...
%                 app.Outputpath_Edit.Value, app.Makeoverlay_CheckBox.Value, ...
%                 app.PreprocessCheckBox.Value, ...
%                 app.Pixellabels_Edit.Value, app.LightningfootagesegmentationUIFigure);
            try
                isDsPassed = false;
                bacthSize = 1;
            [~]=segmentResults(app.Networkfile_Edit.Value, app.Inputfolder_Edit.Value, ...
                app.Outputpath_Edit.Value, app.PreprocessCheckBox.Value, ...
                app.Makeoverlay_CheckBox.Value, ...
                app.Comparewithexpected_CheckBox.Value, app.Pixellabels_Edit.Value,...
                bacthSize,isDsPassed, app.LightningfootagesegmentationUIFigure);
             catch ME
                 app.TextArea.Visible = 'on';
                 app.TextArea.Value = ME.message ;
             end
            % initialise the app
            app.Excecute_Button.Enable = 'on';
             enableEdits(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LightningfootagesegmentationUIFigure = uifigure('Visible', 'off');
            app.LightningfootagesegmentationUIFigure.Name = 'Lightning footage segmentation';
            app.SegmentationPanel = uipanel(app.LightningfootagesegmentationUIFigure);
            app.SegmentationPanel.Title = 'Segmentation';
            app.Networkfile_Edit = uieditfield(app.SegmentationPanel, 'text');
            app.Outputpath_Edit = uieditfield(app.SegmentationPanel, 'text');
            app.Inputfolder_Edit = uieditfield(app.SegmentationPanel, 'text');
            app.Inputfolder_Button = uibutton(app.SegmentationPanel, 'push');
            app.Inputfolder_Button.ButtonPushedFcn = createCallbackFcn(app, @Browse_input, true);
            app.Inputfolder_Button.Icon = 'folder.png';
            app.Inputfolder_Button.Tooltip = {'Browse'};
            app.Inputfolder_Button.Text = '';
            app.Networkfile_Button = uibutton(app.SegmentationPanel, 'push');
            app.Networkfile_Button.ButtonPushedFcn = createCallbackFcn(app, @Browse_network, true);
            app.Networkfile_Button.Icon = 'folder.png';
            app.Networkfile_Button.Tooltip = {'Browse'};
            app.Networkfile_Button.Text = '';
            app.Outputpath_Button = uibutton(app.SegmentationPanel, 'push');
            app.Outputpath_Button.ButtonPushedFcn = createCallbackFcn(app, @Browse_output, true);
            app.Outputpath_Button.Icon = 'folder.png';
            app.Outputpath_Button.Tooltip = {'Browse'};
            app.Outputpath_Button.Text = '';
            app.Comparewithexpected_CheckBox = uicheckbox(app.SegmentationPanel);
            app.Comparewithexpected_CheckBox.ValueChangedFcn = createCallbackFcn(app, @makeVisiable, true);
            app.Comparewithexpected_CheckBox.Tooltip = {'Enable the overlay of the input image and pixel segmentation'};
            app.Comparewithexpected_CheckBox.Text = ' Compare with expected';
            app.Pixellabels_Edit = uieditfield(app.SegmentationPanel, 'text');
            app.Pixellabels_Edit.Visible = 'off';
            app.Pixellabels_Edit.Value = '/home/jason/Desktop/small/mask';
            app.Pixellabels_Button = uibutton(app.SegmentationPanel, 'push');
            app.Pixellabels_Button.ButtonPushedFcn = createCallbackFcn(app, @Browse_labels, true);
            app.Pixellabels_Button.Icon = 'folder.png';
            app.Pixellabels_Button.Visible = 'off';
            app.Pixellabels_Button.Tooltip = {'Browse'};
            app.Pixellabels_Button.Text = '';
            app.Makeoverlay_CheckBox = uicheckbox(app.SegmentationPanel);
            app.Makeoverlay_CheckBox.Tooltip = {'Overlay the labelled-output over the original image'};
            app.Makeoverlay_CheckBox.Text = ' Make overlay';
            app.Makeoverlay_CheckBox.Value = true;
            app.Excecute_Button = uibutton(app.SegmentationPanel, 'push');
            app.Excecute_Button.ButtonPushedFcn = createCallbackFcn(app, @runSegmentation, true);
            app.Excecute_Button.Text = 'Excecute';
            app.Inputfolder_Label = uilabel(app.SegmentationPanel);
            app.Inputfolder_Label.Text = 'Input folder';
            app.Networkfile_Label = uilabel(app.SegmentationPanel);
            app.Networkfile_Label.Text = 'Network file';
            app.Outputpath_Label = uilabel(app.SegmentationPanel);
            app.Outputpath_Label.Text = 'Output path';
            app.Pixellabels_Label = uilabel(app.SegmentationPanel);
            app.Pixellabels_Label.Visible = 'off';
            app.Pixellabels_Label.Text = 'Pixel labels';
            app.PreprocessCheckBox = uicheckbox(app.SegmentationPanel);
            app.PreprocessCheckBox.Tooltip = {'Pre-process the images before segmentation'};
            app.PreprocessCheckBox.Text = ' Pre-process';
            app.PreprocessCheckBox.Value = true;
            app.TextArea = uitextarea(app.SegmentationPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Visible = 'off';
            % Show the figure after all components are created
            app.LightningfootagesegmentationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = labelLightning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LightningfootagesegmentationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LightningfootagesegmentationUIFigure)
        end
    end
end